classdef GUI_InvArduino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArduinoOpenLoopInverterGUIUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        loadscalefromfileButton         matlab.ui.control.Button
        rec_IW_scaled_EditField         matlab.ui.control.NumericEditField
        VdcADCrawLabel_18               matlab.ui.control.Label
        rec_IV_scaled_EditField         matlab.ui.control.NumericEditField
        VdcADCrawLabel_17               matlab.ui.control.Label
        rec_IU_scaled_EditField         matlab.ui.control.NumericEditField
        VdcADCrawLabel_16               matlab.ui.control.Label
        resetscaleButton                matlab.ui.control.Button
        rec_DATA5min_scaled_EditField   matlab.ui.control.NumericEditField
        VdcADCrawLabel_15               matlab.ui.control.Label
        rec_DATA6min_scaled_EditField   matlab.ui.control.NumericEditField
        VdcADCrawLabel_14               matlab.ui.control.Label
        rec_DATA5avg_scaled_EditField   matlab.ui.control.NumericEditField
        VdcADCrawLabel_13               matlab.ui.control.Label
        rec_DATA6avg_scaled_EditField   matlab.ui.control.NumericEditField
        VdcADCrawLabel_12               matlab.ui.control.Label
        rec_Vdc_scaled_EditField        matlab.ui.control.NumericEditField
        VdcADCrawLabel_11               matlab.ui.control.Label
        adjustscaleButton               matlab.ui.control.Button
        PAPlossWEditField               matlab.ui.control.NumericEditField
        PAPlossWEditFieldLabel          matlab.ui.control.Label
        PAIWArmsEditField               matlab.ui.control.NumericEditField
        PAIWArmsEditFieldLabel          matlab.ui.control.Label
        PAIVArmsEditField               matlab.ui.control.NumericEditField
        PAIVArmsEditFieldLabel          matlab.ui.control.Label
        PAIUArmsEditField               matlab.ui.control.NumericEditField
        PAIUArmsEditFieldLabel          matlab.ui.control.Label
        PAIdcArmsEditField              matlab.ui.control.NumericEditField
        PAIdcArmsEditFieldLabel         matlab.ui.control.Label
        PAVdcVrmsEditField              matlab.ui.control.NumericEditField
        PAVdcVrmsEditFieldLabel         matlab.ui.control.Label
        ConnectPowerAnalyzerButton      matlab.ui.control.StateButton
        rec_testvar2_EditField          matlab.ui.control.NumericEditField
        VdcADCrawLabel_10               matlab.ui.control.Label
        rec_testvar1_EditField          matlab.ui.control.NumericEditField
        VdcADCrawLabel_9                matlab.ui.control.Label
        rec_IW_EditField                matlab.ui.control.NumericEditField
        VdcADCrawLabel_8                matlab.ui.control.Label
        rec_IV_EditField                matlab.ui.control.NumericEditField
        VdcADCrawLabel_7                matlab.ui.control.Label
        rec_IU_EditField                matlab.ui.control.NumericEditField
        VdcADCrawLabel_6                matlab.ui.control.Label
        rec_DATA5min_EditField          matlab.ui.control.NumericEditField
        VdcADCrawLabel_5                matlab.ui.control.Label
        rec_DATA6min_EditField          matlab.ui.control.NumericEditField
        VdcADCrawLabel_4                matlab.ui.control.Label
        rec_DATA5avg_EditField          matlab.ui.control.NumericEditField
        VdcADCrawLabel_3                matlab.ui.control.Label
        rec_DATA6avg_EditField          matlab.ui.control.NumericEditField
        VdcADCrawLabel_2                matlab.ui.control.Label
        rec_Vdc_EditField               matlab.ui.control.NumericEditField
        VdcADCrawLabel                  matlab.ui.control.Label
        rec_Status_RunEditField         matlab.ui.control.NumericEditField
        Status_RunEditFieldLabel        matlab.ui.control.Label
        rec_ctrl_TimestampEditField     matlab.ui.control.NumericEditField
        ctrl_TimestampEditFieldLabel    matlab.ui.control.Label
        rec_UDP_TimestampEditField      matlab.ui.control.EditField
        UDP_TimestampEditFieldLabel     matlab.ui.control.Label
        RevisionLabel                   matlab.ui.control.Label
        DataReceivedfromInverterLabel   matlab.ui.control.Label
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        LockComChannelButton            matlab.ui.control.StateButton
        FlashToolArduinoButton          matlab.ui.control.Button
        Simulate                        matlab.ui.control.Button
        TexttoplaceinlogfileEditField   matlab.ui.control.EditField
        TexttoplaceinlogfileEditFieldLabel  matlab.ui.control.Label
        InfoEditField                   matlab.ui.control.EditField
        InfoEditFieldLabel              matlab.ui.control.Label
        Clear_LogBufferButton           matlab.ui.control.Button
        EmergencyStopButton             matlab.ui.control.StateButton
        CosphiTX0Slider                 matlab.ui.control.Slider
        CosphiTX0SliderLabel            matlab.ui.control.Label
        PWMModulationDropDown           matlab.ui.control.DropDown
        PWMModulationDropDownLabel      matlab.ui.control.Label
        HillHoldOperationDropDown       matlab.ui.control.DropDown
        HillHoldOperationDropDownLabel  matlab.ui.control.Label
        StopOperationRamp_DownOFForASCDropDown  matlab.ui.control.DropDown
        StopOperationRamp_DownOFForASCLabel  matlab.ui.control.Label
        PinTX0SyncwithPhaseDropDown     matlab.ui.control.DropDown
        PinTX0SyncwithPhaseDropDownLabel  matlab.ui.control.Label
        AdvancedControlnotoverwrittenfromreplayfileLabel  matlab.ui.control.Label
        DeadtimensSlider                matlab.ui.control.Slider
        DeadtimensLabel                 matlab.ui.control.Label
        DTnsEditField                   matlab.ui.control.NumericEditField
        DTnsLabel                       matlab.ui.control.Label
        FswkHzEditField                 matlab.ui.control.NumericEditField
        fswkHzLabel                     matlab.ui.control.Label
        SpeedHzEditField                matlab.ui.control.NumericEditField
        SpeedHzLabel                    matlab.ui.control.Label
        ModindexEditField               matlab.ui.control.NumericEditField
        ModIndexLabel                   matlab.ui.control.Label
        runxsecsEditField               matlab.ui.control.NumericEditField
        runxsecsLabel                   matlab.ui.control.Label
        LocalIPDropDown                 matlab.ui.control.DropDown
        LocalIPDropDownLabel            matlab.ui.control.Label
        LocalSSIDEditField              matlab.ui.control.EditField
        LocalSSIDEditFieldLabel         matlab.ui.control.Label
        LocalIPEditField                matlab.ui.control.EditField
        LocalIPEditField_2Label         matlab.ui.control.Label
        FswkHzSlider                    matlab.ui.control.Slider
        FswkHzSliderLabel               matlab.ui.control.Label
        RunxSecSlider                   matlab.ui.control.Slider
        RunxSecSliderLabel              matlab.ui.control.Label
        SpeedHzSlider                   matlab.ui.control.Slider
        SpeedHzSliderLabel              matlab.ui.control.Label
        ModindexSlider                  matlab.ui.control.Slider
        ModindexSliderLabel             matlab.ui.control.Label
        Save_LogfileButton              matlab.ui.control.Button
        Replay_LogfileButton            matlab.ui.control.Button
        WIFIInverterControlLabel        matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.StateButton
        RUNButton                       matlab.ui.control.StateButton
        DirectControlCheckBox           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
            u = udpport("LocalPort",60000,"EnablePortSharing",false);
            arduinoport = 50000;
            PWMclock = 100e6;

            data2send;% = uint16([0 0 0 0 0 0 0 0 0 0]);
            count = 0;
            t = timer('Period',0.5,'ExecutionMode','fixedRate','BusyMode','queue');
            Revision = "Revision V1.1";
            
            %filename = [pwd,'\logfiles\logfile_',datestr(now,'yyyymmdd_HHMMSS'),'.xls']
            filename = [pwd,'\logfiles\logfile_',string(datetime ('now','Format','yyyyMMdd_HHmmSS')),'.xls'];
            logdata_rec = {};
            logdata_snd = [];
            logdata_snd_real = [];
            logdata_events = {};
            
            rep_progress; %handle for the process dialog
            rep_data;     %storage for data import
            rep_counter = [];
            rep_t = timer('Period',0.5,'ExecutionMode','fixedRate','BusyMode','queue');
            rep_starttime = [];

            time2run = [];

            time_programstart = [];
            
            ZES = [];% = tcpclient("168.192.178.41",5025,"ConnectTimeout",1);
            %ZES_t = timer('Period',2,'ExecutionMode','fixedRate','BusyMode','queue');
            ZES_log = [];
           
            UserFolder = [];
            UserFile = 'InverterCtrlConfig.xlsx';
            SimCondition = {'Vdc [V]',450;'Load L [uH]',250;'Load R [mOhm]',10};
            ScaleVal = {'Vdc_RawOffset [RAW]',0;'Vdc_Raw2Vadc [V/RAW]',5/4096;'Vdc_Vgain [V/V]',115;'Temp_RawOffset_LS [RAW]',565;'Temp_RawOffset_HS [RAW]',565;'Temp_Raw2Volt [V/RAW]',0.0003486;'Vts 25°C [V]',2.28;'Vtk [mV/K]',-5.5;'Current [A/RAW]',0.1};

            SessionKey = uint16(0);
            LockMode = uint16(0);
            
    end
    
    methods (Access = private)
        
        function udpReceiveCallback(app,src,~)
                test = 1;
               data = read(src,15,"uint16");  %read the data
               flush(app.u,"input");          %flush the remaining data from input buffer

               if (app.LockMode ~= 0) && (app.LockMode ~= data(1)) && (app.LockMode ~= bitxor(data(1),data(2)))
                    %ignore Message as not matching to the KEY
                    return
               end
                               
               [timerel, datecode] = get_timestamp(app);
               app.rec_UDP_TimestampEditField.Value = string(datecode);
                              

               ctrl_timestamp = uint32(data(3)) + (2^16*uint32(data(4)));
               app.rec_ctrl_TimestampEditField.Value = double(ctrl_timestamp);

               app.rec_Status_RunEditField.Value = double(data(5));
               
               app.rec_Vdc_EditField.Value = double(data(6));
               data_scaled(1) = (double(data(6)) - cell2mat(app.ScaleVal(1,2)))*cell2mat(app.ScaleVal(2,2))*cell2mat(app.ScaleVal(3,2));
               app.rec_Vdc_scaled_EditField.Value = data_scaled(1);

               
               app.rec_DATA6avg_EditField.Value = double(data(7));
               app.rec_DATA5avg_EditField.Value = double(data(8));
               app.rec_DATA6min_EditField.Value = double(data(9));
               app.rec_DATA5min_EditField.Value = double(data(10));               

               
               data_scaled(2) = ((((double(data(7)) - cell2mat(app.ScaleVal(4,2)))*cell2mat(app.ScaleVal(6,2))) - cell2mat(app.ScaleVal(7,2)))/(1e-3*cell2mat(app.ScaleVal(8,2))))+25; 
               data_scaled(3) = ((((double(data(8)) - cell2mat(app.ScaleVal(5,2)))*cell2mat(app.ScaleVal(6,2))) - cell2mat(app.ScaleVal(7,2)))/(1e-3*cell2mat(app.ScaleVal(8,2))))+25;
               data_scaled(4) = ((((double(data(9)) - cell2mat(app.ScaleVal(4,2)))*cell2mat(app.ScaleVal(6,2))) - cell2mat(app.ScaleVal(7,2)))/(1e-3*cell2mat(app.ScaleVal(8,2))))+25;
               data_scaled(5) = ((((double(data(10)) - cell2mat(app.ScaleVal(5,2)))*cell2mat(app.ScaleVal(6,2))) - cell2mat(app.ScaleVal(7,2)))/(1e-3*cell2mat(app.ScaleVal(8,2))))+25;
               app.rec_DATA6avg_scaled_EditField.Value = data_scaled(2);
               app.rec_DATA5avg_scaled_EditField.Value = data_scaled(3);
               app.rec_DATA6min_scaled_EditField.Value = data_scaled(4);
               app.rec_DATA5min_scaled_EditField.Value = data_scaled(5);


                %app.rec_IU_EditField.Value = double(data(11));
                app.rec_IU_EditField.Value = double(typecast(uint16(data(11)),'int16'));
                app.rec_IV_EditField.Value = double(typecast(uint16(data(12)),'int16'));
                app.rec_IW_EditField.Value = double(typecast(uint16(data(13)),'int16'));
                data_scaled(6) = cell2mat(app.ScaleVal(9,2))*app.rec_IU_EditField.Value;
                data_scaled(7) = cell2mat(app.ScaleVal(9,2))*app.rec_IU_EditField.Value;
                data_scaled(8) = cell2mat(app.ScaleVal(9,2))*app.rec_IU_EditField.Value;
                app.rec_IV_scaled_EditField.Value = data_scaled(6);
                app.rec_IV_scaled_EditField.Value = data_scaled(7); 
                app.rec_IW_scaled_EditField.Value = data_scaled(8);
 

                app.rec_testvar1_EditField.Value = double(data(14));
                app.rec_testvar2_EditField.Value = double(data(15));


               app.logdata_rec(end+1,:) = {[timerel, datecode],[ctrl_timestamp data(5:end)],0,data_scaled};

        end

        function timerfcn(app,~,~)  
            try
            sendUDP(app);
            
            if (app.rec_Status_RunEditField.Value == 1)&&(~isempty(app.time2run))
                x=round(app.time2run - posixtime(datetime('now','TimeZone','local')));
                if isinf(x) %display stop in direct control
                    app.RUNButton.Text = 'STOP';
                else %display the remaining runtime                    
                    app.RUNButton.Text = {'RUN',['Inverter sill run for ',num2str(x),' s']};
                end
            
            else
                app.RUNButton.Text = 'RUN';
            end
            catch
                app.InfoEditField.Value = 'something was going wrong during timerfcn execution';
            end

        end

        function reptimerfcn(app,~,~)
            %disp(app.rep_counter)
            try
            
            [timerel, datecode] = get_timestamp(app);
            time_since_filestart = posixtime(datetime('now','TimeZone','local'))-app.rep_starttime;
            %disp(time_since_filestart)

            if ((app.rep_counter < length(app.rep_data'))&&(~app.rep_progress.CancelRequested))
                if (time_since_filestart>app.rep_data(app.rep_counter,1)) %check if new data to apply
                    %disp('apply data')

                    % update the parameters and last step actiate                    
                    app.ModindexEditField.Value = app.rep_data(app.rep_counter,4);
                    app.SpeedHzEditField.Value = app.rep_data(app.rep_counter,5);
                    app.FswkHzEditField.Value = app.rep_data(app.rep_counter,6);
                    app.DTnsEditField.Value = app.rep_data(app.rep_counter,7);


                    %last step activate
                    if (app.rep_data(app.rep_counter,3) == 0)
                        app.runxsecsEditField.Value = app.rep_data(app.rep_counter,3);
                        app.DirectControlCheckBox.Value = 0;
                        app.RUNButton.Value = 0;
                        RUNButtonValueChanged(app, 0);
                    elseif (app.rep_data(app.rep_counter,3) > 3600000) %more than 1000h
                        app.DirectControlCheckBox.Value = 1;
                        app.RUNButton.Value = 1;
                        RUNButtonValueChanged(app, 0);
                     else
                        app.DirectControlCheckBox.Value = 0;
                        app.runxsecsEditField.Value = app.rep_data(app.rep_counter,3);
                        app.RUNButton.Value = 1;
                        RUNButtonValueChanged(app, 0);
                    end


                    app.rep_counter = app.rep_counter+1;        %count up for next 
                else
                    %disp('wait till next data')
                end
                
                % update progress bar
                app.rep_progress.Message = sprintf('Time %.0f of %.0f Seconds',time_since_filestart,app.rep_data(end,1));
                app.rep_progress.Value = time_since_filestart/app.rep_data(end,1);


             else
                 app.RUNButton.Value = 0;
                 stop(app.rep_t)             %stop timer
                 close(app.rep_progress)     %close progress dialog
 
                 %save event in logfile
                 if (app.rep_counter == length(app.rep_data'))
                     app.logdata_events(end+1,:) = {[timerel, datecode],'Replay ENDED','Successful'};
                 else
                     app.logdata_events(end+1,:) = {[timerel, datecode],'Replay ENDED','Canceled'};
                 end
            end
            catch
                app.InfoEditField.Value = ['Error during Replay at Position',num2str(app.rep_counter)];
            end
            

        end


        function sendUDP(app)
            app.arduinoport = 50000;
            app.count = app.count+1; 

            if (app.RUNButton.Value == 0)
                runxmsec.unit32 = uint32(0);
            else
                if (app.DirectControlCheckBox.Value == 0)
                    runxmsec.unit32 = uint32(1e3 * app.runxsecsEditField.Value);                    
                else
                    runxmsec.unit32 = uint32(hex2dec('0xFFFFFFFF'));
                end
            end
            
            runxmsec.h = uint16(bitshift(runxmsec.unit32,-16));
            runxmsec.l = uint16(bitand(runxmsec.unit32,hex2dec('0xFFFF')));

            mod = app.ModindexEditField.Value;
            speed = app.SpeedHzEditField.Value;
            
            fsw = app.FswkHzEditField.Value;
            dt = app.DTnsEditField.Value;

            %comm_timestamp = app.count;

            [possync,hillholdpos] = getPosSync_HillholdPos(app);
 
            try %Stop with RAMP or ASC
                OpMode = uint16(find(strcmp(app.StopOperationRamp_DownOFForASCDropDown.Items, app.StopOperationRamp_DownOFForASCDropDown.Value))-1);
            catch
                OpMode = uint16(0); %default is ramp
            end

            try
                PWMModulation = uint16(find(strcmp(app.PWMModulationDropDown.Items, app.PWMModulationDropDown.Value))-1);
            catch
                PWMModulation = uint16(0); %default is SVPWM
            end           


            act_timestamp.uint32 = uint32(posixtime(datetime('now','TimeZone','local')));
            act_timestamp.h = uint16(bitshift(act_timestamp.uint32,-16));
            act_timestamp.l = uint16(bitand(act_timestamp.uint32,hex2dec('0xFFFF')));

            if (app.LockMode == 0)
                app.data2send = uint16([runxmsec.l runxmsec.h storedInteger(fi(0.01*mod,0,16,14)) storedInteger(fi(speed,0,16,6)) uint16((0.5*app.PWMclock)/(1e3*fsw)) uint16(dt/(1e9/100e6)) possync hillholdpos OpMode PWMModulation 0 0 act_timestamp.l act_timestamp.h]);
            else
                app.data2send = uint16([runxmsec.l runxmsec.h storedInteger(fi(0.01*mod,0,16,14)) storedInteger(fi(speed,0,16,6)) uint16((0.5*app.PWMclock)/(1e3*fsw)) uint16(dt/(1e9/100e6)) possync hillholdpos OpMode PWMModulation 0 0 act_timestamp.l app.LockMode]);
            end

            %app.InfoEditField.Value = [dec2hex(runxmsec.l),'  ',dec2hex(runxmsec.h)];

            %disp(app.data2send)
            if (app.ConnectButton.Value == 1)
                write(app.u,app.data2send,"uint16","255.255.255.255",app.arduinoport);                  
            end
            
            if isempty(app.logdata_snd)
                app.logdata_snd(end+1,:) = app.data2send;
                app.logdata_snd_real(end+1,:) = dat2real(app,app.data2send);                
            elseif  ~isequal(app.data2send(1:end-2),app.logdata_snd(end,1:end-2))      %Log only when data changed
                app.logdata_snd(end+1,:) = app.data2send;
                app.logdata_snd_real(end+1,:) = dat2real(app,app.data2send);                
            else
                %do nothing
            end

        end

         function localIP = getLocalIP(app)
            try
                [~, result] = system('ipconfig');
                IP = ["auto detect","0.0.0.0"];
                i = strfind(result,"IPv4 Address");
                ii = 3;
                for pos1=i

                    pos2 = pos1 + min(strfind(result(pos1:end),":"));
                    pos3 = pos1 + min(strfind(result(pos1:end),"Subnet"));
                    IP(ii) = result(pos2+1:pos3-6);
                    ii=ii+1;
                end
                IP = IP(end:-1:1);
                %disp(IP)
                app.LocalIPDropDown.Items = IP;
                localIP = IP(1);
            catch
                app.StatusEditField.Value = 'not able to get local IP';
            end
        end
        
        function localSSID = getLocalSSID(app)
            try
                [~,result] = system('Netsh WLAN show interfaces');
                             
                pos1 = min(strfind(result,"SSID"));                            
                pos2 = pos1 + min(strfind(result(pos1:end),":"));
                pos3 = pos1 + min(strfind(result(pos1:end),"BSSID"));
                localSSID = result(pos2+1:pos3-5);
            
            catch
                app.StatusEditField.Value = 'Could not detect WIFI SSID';
            end
        end

        
        function data_real = dat2real(app,data_fixed)

            [timerel, datecode] = get_timestamp(app);
            data_real(1) = timerel;
            data_real(2) = datecode;
            data_real(3) = double(uint32(data_fixed(1)) + 2^16*uint32(data_fixed(2)))/1000; %run x sec
            data_real(4) = 100* 2^-14 * double(data_fixed(3));           %Mod %
            data_real(5) = 2^-6 * double(data_fixed(4));            %fout Hz
            data_real(6) = 1e-3*(0.5*100e6)/double(data_fixed(5));  %kHz            
            data_real(7) = 10*double(data_fixed(6));  %kHz
            data_real(8) = double(data_fixed(7));    %degree
            data_real(9) = 360*double(data_fixed(8))/1024;    %degree
            data_real(10) = data_fixed(9);    %Opmode ASC
            data_real(11) = data_fixed(10);    %PWM Modulat
            data_real(12) = data_fixed(11);    %Timestamps
            data_real(13) = data_fixed(12);    %Timestamps
                        
            %    [runxmsec.l runxmsec.h storedInteger(fi(0.01*mod,0,16,14)) storedInteger(fi(speed,0,16,6)) uint16((0.5*app.PWMclock)/(1e3*fsw)) uint16(dt/(1e9/100e6)) possync Hillholdpos OpMode PWMModulation 0 0 act_timestamp.l act_timestamp.h]);
        end

        function [timerelprogstart, datecode] = get_timestamp(app)
            try
                timerelprogstart = posixtime(datetime('now','TimeZone','local'))-app.time_programstart;
                datecode = str2double(string(datetime('now','TimeZone','local','Format','yyyyMMddHHmmss')));          
            catch
                app.InfoEditField.Value = 'something wrong at timestamps';
                timerelprogstart = 0;
                datecode = 0;
            end
        end

        function result = PA_init(app,IP)
            % Create TCP object
            try
                app.ZES = tcpclient(IP,5025,"ConnectTimeout",1);            
                app.ZES.configureTerminator("LF");
                          
                writeline(app.ZES, '*IDN?');
                % Request the data
                app.InfoEditField.Value = readline(app.ZES);  
                
                %activate now the callback and the continous datatransfer
                configureCallback(app.ZES,"Terminator",@app.PAReceiveCallback); 
                writeline(app.ZES, ':trig:act;:fetch:volt:trms4?;:fetch:current:trms4?;:fetch:current:trms1?;:fetch:current:trms2?;:fetch:current:trms3?;:fetch:power:loss?2')
                writeline(app.ZES, ':init:cont on')
                %writeline(app.ZES, ':init:cont off')

            catch
                app.InfoEditField.Value = 'something going wrong at PA connect';
            end
            result = app.InfoEditField.Value;
        end


        function PA_close(app)
            try
                writeline(app.ZES, ':init:cont off')
            catch
            end

            try
                flush(app.ZES)
                delete(app.ZES)
            catch
            end

            try                
                clear(app.ZES)
             catch
            end         
        end


        function PAshowandlog(app,data)  
            try     
                    timerel = round(posixtime(datetime('now','TimeZone','local'))-app.time_programstart,1);
                    timeabs = str2double(string(datetime('now','TimeZone','local','Format','yyyyMMddHHmmss')));
                    app.ZES_log(end+1,:) = [timerel,timeabs,data];
                    app.PAVdcVrmsEditField.Value = data(1);
                    app.PAIdcArmsEditField.Value = data(2);

                    app.PAPlossWEditField.Value = data(4);

                    app.PAIUArmsEditField.Value = data(5);
                    app.PAIVArmsEditField.Value = data(6);
                    app.PAIWArmsEditField.Value = data(7);             

            catch
                app.InfoEditField.Value = 'something wrong at ZES display';
            end

        end


        function PAReceiveCallback(app,~,~)
            try              
                result = readline(app.ZES);
                disp(result);

                result = str2double(regexp(result,";",'split'));
                Udcrms = result(1);
                Idcrms = result(2);
                Pdc = Udcrms * Idcrms;                    
                IUrms = result(3);
                IVrms = result(4);
                IWrms = result(5);

                Ploss = result(6);             
                
            catch
                app.InfoEditField.Value = 'error PA read';
            end
            
            PAshowandlog(app,[Udcrms,Idcrms,Pdc,Ploss,IUrms,IVrms,IWrms]);  
            %output = [Udcrms,Idcrms,Pdc,Ploss,IUrms,IVrms,IWrms];

        end

        
        function port = getArduinoCOMport(app)
            try
                [status, cmdOut] = system('powershell -Command "Get-CimInstance -Class Win32_SerialPort"');
                port=[];
                pos1 = strfind(cmdOut,'Arduino NANO 33 IoT'); %find the 1st position where Arduino is mentioned
                if ~isempty(pos1)    
                    pos2 = pos1(1)-1+strfind(cmdOut(pos1(1):end),'COM');  %find from Arduino mentioned position the COM port entry
                    port = regexprep(cmdOut(pos2(1):pos2(1)+4),')','');  %cut brackets, blanks and just report the COM port
                    app.InfoEditField.Value = ['Arduino Detected at PORT: ',port];
                else
                    app.InfoEditField.Value = ['No Arduino Detected'];
                end            
            catch
                app.InfoEditField.Value = ['Something was going wrong during COM port detection'];
            end            
         end
        
        function fcn_devicedriverinst(app)
            %try to install device drivers
            selection = uiconfirm(app.ArduinoOpenLoopInverterGUIUIFigure,{'Maybe missing device drivers?','Do you want to install device drivers?','Will need admin rights.','Retry to flash after driver installed.'},...
            'Confirm Device Driver Install','Icon','info','Options',{'64bit','32bit','Cancel'});
            switch selection
                case '64bit'
                    try
                        [status,cmdOut] = system('dpinst-amd64.exe'); %64bit                        
                    catch
                        app.InfoEditField.Value = 'Device Driver Install 64bit failed.';
                    end
                case '32bit'
                    try
                        [status,cmdOut] = system('dpinst-x86.exe'); %32bit
                    catch
                        app.InfoEditField.Value = 'Device Driver Install 32bit failed.';
                    end

                 case 'Cancel'
                    %do nothing
            end            
        end

        
        function [possync,hillholdpos] = getPosSync_HillholdPos(app)
            try
                SyncSelect = find(strcmp(app.PinTX0SyncwithPhaseDropDown.Items, app.PinTX0SyncwithPhaseDropDown.Value));
                cosphi = acos(app.CosphiTX0Slider.Value)*(1024/(2*pi));
                possync = uint16((1024*(SyncSelect/3)*(2*pi)/(2*pi)) + (pi/2)*1024/(2*pi) + cosphi);
            catch
                possync = uint16(0);
            end
            

            try
                PosSelect = find(strcmp(app.HillHoldOperationDropDown.Items, app.HillHoldOperationDropDown.Value))-1;
                if (PosSelect ~= 0)
                    hillholdpos = uint16(1 + (PosSelect*pi()/3)*1024/(2*pi()));
                else
                    hillholdpos = uint16(0); %normal
                end
            catch
                hillholdpos = uint16(0);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.t.TimerFcn = @app.timerfcn;
                app.rep_t.TimerFcn = @app.reptimerfcn;
                %app.ZES_t.TimerFcn = @app.PAtimer_fct;
            catch
                delete(timerfindall)
                app.t = timer('Period',0.5,'ExecutionMode','fixedRate','BusyMode','queue');
                app.rep_t = timer('Period',0.5,'ExecutionMode','fixedRate','BusyMode','queue');
                %app.ZES_t.TimerFcn = timer('Period',2,'ExecutionMode','fixedRate','BusyMode','queue');
                app.t.TimerFcn = @app.timerfcn;
                app.rep_t.TimerFcn = @app.reptimerfcn;
                %app.ZES_t.TimerFcn = @app.PAtimer_fct;
            end

            app.RunxSecSlider.Value = app.runxsecsEditField.Value;
            app.ModindexSlider.Value = app.ModindexEditField.Value;
            app.SpeedHzSlider.Value = app.SpeedHzEditField.Value;
            app.FswkHzSlider.Value = app.FswkHzEditField.Value;
            app.DeadtimensSlider.Value = app.DTnsEditField.Value;
            app.RevisionLabel.Text = app.Revision;

            app.time_programstart = posixtime(datetime('now','TimeZone','local'));
            app.SessionKey = randi([1 255],1) * 2^8;

            try
                app.UserFolder = winqueryreg('HKEY_CURRENT_USER','Software\Microsoft\Windows\CurrentVersion\Explorer\Shell Folders','AppData');
                if isfile(fullfile(app.UserFolder,'',app.UserFile))
                    %overwrite default data if file available
                    app.SimCondition = readcell(fullfile(app.UserFolder,'',app.UserFile),'Sheet','Simulation');
                    app.ScaleVal = readcell(fullfile(app.UserFolder,'',app.UserFile),'Sheet','Scale');
                else
                    writecell(app.SimCondition,fullfile(app.UserFolder,'',app.UserFile),'Sheet','Simulation');
                    writecell(app.ScaleVal,fullfile(app.UserFolder,'',app.UserFile),'Sheet','Scale');
                end
            catch
            end  

            %test = dir(fullfile(ctfroot, '**', 'bossac.exe'));
            %app.InfoEditField.Value = test.folder;

        end

        % Close request function: ArduinoOpenLoopInverterGUIUIFigure
        function ArduinoOpenLoopInverterGUIUIFigureCloseRequest(app, event)
            
            try
                %only save at normal close request
                if strcmp(event.EventName,'Close')
                    writecell(app.SimCondition,fullfile(app.UserFolder,'',app.UserFile),'Sheet','Simulation');
                    writecell(app.ScaleVal,fullfile(app.UserFolder,'',app.UserFile),'Sheet','Scale');
                end
            catch
            end

            try
                stop(app.t)
                stop(app.rep_t)
                delete(timerfindall)
            catch
            end

            try
                PA_close(app);
            catch
            end

            delete(app)
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            app.InfoEditField.Value = 'Connect Clicked';
            [timerel, datecode] = get_timestamp(app);

            if (value == 1)

                if (app.LocalIPDropDown.Value == "auto detect")
                    app.LocalIPEditField.Value = getLocalIP(app);
                else
                    app.LocalIPEditField.Value = app.LocalIPDropDown.Value;
                end                    
                app.LocalSSIDEditField.Value = getLocalSSID(app);
                

                if isvalid(app.u)
                    configureCallback(app.u,"off"); 
                end


                delete(app.u)
                app.u = udpport("LocalHost",app.LocalIPEditField.Value,"LocalPort",60000,"EnablePortSharing",false);
                set(app.u,"EnableBroadcast",true);
                set(app.u,"OutputDatagramSize",14*2);
                configureTerminator(app.u,"CR/LF","CR/LF");
                configureCallback(app.u,"byte",2*4 + 11*2,@app.udpReceiveCallback); 
                             

                app.logdata_events(end+1,:) = {[timerel, datecode],'UDP Connect to Inverter','-'};
                            
                if strcmp(get(app.t,'Running'),'off')           
                  start(app.t);
                end

                app.ConnectButton.Text = "Disconnect from DUT";
                app.ConnectButton.BackgroundColor = [0.39,0.83,0.07];
                app.LockComChannelButton.Visible = "on";


            else
                if isvalid(app.u)
                    configureCallback(app.u,"off"); 
                end
                delete(app.u)
                stop(app.t);

                app.ConnectButton.Text = "Connect";
                app.ConnectButton.BackgroundColor = [0.93,0.69,0.13];
                app.LockComChannelButton.Visible = "off";
                app.LockComChannelButton.Text = 'LockComChannel';
                app.LockMode = uint16(0);

                app.logdata_events(end+1,:) = {[timerel, datecode],'UDP Disconnect from Inverter','-'};
            
            end
            
        end

        % Value changed function: DirectControlCheckBox
        function DirectControlCheckBoxValueChanged(app, event)
            value = app.DirectControlCheckBox.Value;
            if (value == 1)
                %direct control
                app.RunxSecSlider.Visible = "off";
                app.runxsecsEditField.Visible = "off";
            else
                %run x sec control
                app.RunxSecSlider.Visible = "on";
                app.runxsecsEditField.Visible = "on";
                app.RUNButton.Value = 0;
            end
        end

        % Value changed function: RUNButton
        function RUNButtonValueChanged(app, event)
            %value = app.RUNButton.Value;
               
            sendUDP(app);              
            app.InfoEditField.Value = 'Run Botton Clicked';

            if (app.DirectControlCheckBox.Value == 0)&&(app.rec_Status_RunEditField.Value == 0)
                app.RUNButton.Value = 0;    %reset the pressed button after sending the message
                app.time2run = posixtime(datetime('now','TimeZone','local')) + app.runxsecsEditField.Value;
                %posixtime(datetime('now','TimeZone','local')

            elseif (app.DirectControlCheckBox.Value == 1) %direct control
                app.time2run = inf;
            else

            end


        end

        % Value changed function: runxsecsEditField
        function runxsecsEditFieldValueChanged(app, event)
            value = app.runxsecsEditField.Value;

            if value < app.RunxSecSlider.Limits(1)
                app.RunxSecSlider.Value = app.RunxSecSlider.Limits(1);
                app.RunxSecSlider.FontColor = [1 0 0];

            elseif value > app.RunxSecSlider.Limits(2)
                app.RunxSecSlider.Value = app.RunxSecSlider.Limits(2);
                app.RunxSecSlider.FontColor = [1 0 0];

            else                
                app.RunxSecSlider.Value = value;
                app.RunxSecSlider.FontColor = [0 0 0];
            end
        end

        % Value changing function: RunxSecSlider
        function RunxSecSliderValueChanging(app, event)
            changingValue = event.Value;
            app.runxsecsEditField.Value = round(changingValue,1);
            app.RunxSecSlider.FontColor = [0 0 0];  
        end

        % Value changed function: ModindexEditField
        function ModindexEditFieldValueChanged(app, event)
            value = app.ModindexEditField.Value;

            if value < app.ModindexSlider.Limits(1)
                app.ModindexSlider.Value = app.ModindexSlider.Limits(1);
                app.ModindexSlider.FontColor = [1 0 0];

            elseif value > app.ModindexSlider.Limits(2)
                app.ModindexSlider.Value = app.ModindexSlider.Limits(2);
                app.ModindexSlider.FontColor = [1 0 0];

            else                
                app.ModindexSlider.Value = value;
                app.ModindexSlider.FontColor = [0 0 0];
            end
            
        end

        % Value changing function: ModindexSlider
        function ModindexSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ModindexEditField.Value = round(changingValue);
            app.ModindexSlider.FontColor = [0 0 0];            
        end

        % Value changed function: SpeedHzEditField
        function SpeedHzEditFieldValueChanged(app, event)
            value = app.SpeedHzEditField.Value;
     
            if value < app.SpeedHzSlider.Limits(1)
                app.SpeedHzSlider.Value = app.SpeedHzSlider.Limits(1);
                app.SpeedHzSlider.FontColor = [1 0 0];

            elseif value > app.SpeedHzSlider.Limits(2)
                app.SpeedHzSlider.Value = app.SpeedHzSlider.Limits(2);
                app.SpeedHzSlider.FontColor = [1 0 0];

            else                
                app.SpeedHzSlider.Value = value;
                app.SpeedHzSlider.FontColor = [0 0 0];
            end
        end

        % Value changing function: SpeedHzSlider
        function SpeedHzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SpeedHzEditField.Value = round(changingValue,1);
            app.SpeedHzSlider.FontColor = [0 0 0];            
        end

        % Value changed function: FswkHzEditField
        function FswkHzEditFieldValueChanged(app, event)
            value = app.FswkHzEditField.Value;
            
            if value < app.FswkHzSlider.Limits(1)
                app.FswkHzSlider.Value = app.FswkHzSlider.Limits(1);
                app.FswkHzSlider.FontColor = [1 0 0];

            elseif value > app.FswkHzSlider.Limits(2)
                app.FswkHzSlider.Value = app.FswkHzSlider.Limits(2);
                app.FswkHzSlider.FontColor = [1 0 0];

            else                
                app.FswkHzSlider.Value = value;
                app.FswkHzSlider.FontColor = [0 0 0];
            end
        end

        % Value changing function: FswkHzSlider
        function FswkHzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.FswkHzEditField.Value = round(changingValue,1);  
            app.FswkHzSlider.FontColor = [0 0 0];
        end

        % Value changed function: DTnsEditField
        function DTnsEditFieldValueChanged(app, event)
            value = app.DTnsEditField.Value;
            app.DeadtimensSlider.Value = value;    
        end

        % Value changing function: DeadtimensSlider
        function DeadtimensSliderValueChanging(app, event)
            changingValue = event.Value;
            app.DTnsEditField.Value = round(changingValue,-1); 
        end

        % Button pushed function: Replay_LogfileButton
        function Replay_LogfileButtonPushed(app, event)
            [file,location] = uigetfile(fullfile(pwd,'logfiles','*.xls'),'Select an File for Replay');

            [timerel, datecode] = get_timestamp(app);
            
            app.rep_data = []; %empty vector
            try 
                %load file into local variable
                app.rep_data = readmatrix([location,file],'Sheet','Sent_RealValue','NumHeaderLines', 1);
                app.rep_data(:,1) = app.rep_data(:,1) - app.rep_data(find(app.rep_data(:,3)~=0,1),1) + 1 ; %substract the time where 1st time a start is sent
            catch
                app.InfoEditField.Value = 'file was not loaded due to error';
            end
            
            if ~isempty(app.rep_data)
                %create process dialog bar
                app.rep_progress = uiprogressdlg(app.ArduinoOpenLoopInverterGUIUIFigure,'Title','Replay in Progress','Cancelable','on');
                app.rep_progress.Message = sprintf('Time %.0f of %.0f Seconds',0,app.rep_data(end,1));
                
                %windows should not disapear
                focus(app.ArduinoOpenLoopInverterGUIUIFigure)

                %connect if not yet done
                app.ConnectButton.Value = 1;
                ConnectButtonValueChanged(app, event);
            
                %save event in logfile
                app.logdata_events(end+1,:) = {[timerel, datecode],'Replay started',[location,file]};
                
                % replay counter to 1
                app.rep_counter = 1;

                % prep and start replay timer and count up in the function
                app.rep_starttime = posixtime(datetime('now','TimeZone','local'));
                start(app.rep_t);
            end
        end

        % Button pushed function: Save_LogfileButton
        function Save_LogfileButtonPushed(app, event)
            
            if (~isempty(app.logdata_snd))||(~isempty(app.logdata_rec))

                prompt = {'Enter Filename'};
                dlgtitle = 'Input';
                fieldsize = [1 80];
                definput = {''};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput);

                app.filename = [pwd,'\logfiles\logfile_',char(datetime('now','TimeZone','local' , 'Format','yyyyMMdd_HHmmss')),'_',char(answer),'.xls'];            

                try
                    if ~exist('logfiles', 'dir')
                        mkdir('logfiles')
                    end
                    
                    writecell({'Time Sent relative to Program Start [s]','Time [s]','Run x Seconds','Mod [%]','Speed [Hz)]','fsw [kHz]','Deadtime [ns]','Position Sync','Hillhold','OpMode','PWM Modulatioin','reserved','reserved'},app.filename,'Sheet','Sent_RealValue');
                    writematrix(app.logdata_snd_real,app.filename,'Sheet','Sent_RealValue','WriteMode','append')


                    writecell({'Time Received relative to Program Start [s]','Time Received [YYYYMMDDHHMMSS]','Ctrl_Timestamp [+1 each step]','Run_Status','Vdc','Temp LS_W [cycle averaged]','Temp HS_W [cycle averaged]','Temp LS_W [min per cycle]','Temp HS_W [min per cycle]','I_U [raw RMS]','I_V [raw RMS]','I_W [raw RMS]','Testvar 1','Testvar 1','0','Vdc_scaled [V]','Temp LS_W scaled [cycle avg]','Temp HS_W scaled [cycle avg]','Temp LS_W scaled [min per cycle]','Temp HS_W scaled [min per cycle]','Current U scaled [Arms]','Current V scaled [Arms]','Current W scaled [Arms]'},app.filename,'Sheet','Received');
                    writecell(app.logdata_rec,app.filename,'Sheet','Received','WriteMode','append')

                    writecell(app.ScaleVal,fullfile(app.UserFolder,'',app.UserFile),'Sheet','Scale');

                    writecell({'runxmsec low','runxmsec high','Mod [fixedpoint(0,16,14)]','Speed [fixedpoint(0,16,16)]','Period [Reg]','Deadtime [Reg]','Position Sync','Hillhold','OpMode','PWM Modulatioin','reserved','reserved','timestamp_low','timestamp_high'},app.filename,'Sheet','Sent_RAW');
                    writematrix(app.logdata_snd,app.filename,'Sheet','Sent_RAW','WriteMode','append')


                    writecell({'Time relative to Program Start [s]','Timestamp [YYYYMMDDHHMMSS]','Event','Filename, Remarks'},app.filename,'Sheet','Events');
                    writecell(app.logdata_events,app.filename,'Sheet','Events','WriteMode','append')

                    if ~isempty(app.ZES_log)
                        writecell({'Time Sent relative to Program Start [s]','Timestamp [YYYYMMDDHHMMSS]','Udc [V]','Idc [I]','Pdc [W]','Ploss [W]','IU_rms [A]','IW_rms [A]','IW_rms [A]'},app.filename,'Sheet','Received_PA');
                        writematrix(app.ZES_log,app.filename,'Sheet','Received_PA','WriteMode','append');
                    end

                    uialert(app.ArduinoOpenLoopInverterGUIUIFigure,['File written: ',app.filename],'Logfile Written',"Icon","info");

                catch
                    app.InfoEditField.Value = 'Error at Logfile Write';
                end
            else
                app.InfoEditField.Value = 'Nothing to Log';
            end


            %clear storage
            app.logdata_rec = {};
            app.logdata_snd = [];
            app.logdata_snd_real = [];
            app.logdata_events = {};
            app.ZES_log = [];
        end

        % Value changed function: HillHoldOperationDropDown
        function HillHoldOperationDropDownValueChanged(app, event)
            value = app.HillHoldOperationDropDown.Value;

            if ~strcmp(value,app.HillHoldOperationDropDown.Items(1))
                %disp('Hillhold')
                app.SpeedHzSlider.Visible = "off";
                app.SpeedHzEditField.Visible = "off";
            else
                app.SpeedHzSlider.Visible = "on";
                app.SpeedHzEditField.Visible = "on";
            end
           
        end

        % Value changed function: EmergencyStopButton
        function EmergencyStopButtonValueChanged(app, event)
            value = app.EmergencyStopButton.Value;

            [timerel, datecode] = get_timestamp(app);
            %disp(value)
            if (app.ConnectButton.Value == 1)
                if (value == 1)
                   %Prep Vector for the Emergency Stop
                   runxmsec.l = 0;
                   runxmsec.h = 0;
                   act_timestamp.l = uint16(hex2dec('0xFFFF'));
                   act_timestamp.h = uint16(hex2dec('0xFFFF'));

                   %Send Emergency Stop Command
                   app.data2send = uint16([runxmsec.l runxmsec.h 0 0 0 0 0 0 0 0 0 0 act_timestamp.l act_timestamp.h]);
                   write(app.u,app.data2send,"uint16","255.255.255.255",app.arduinoport);

                   uialert(app.ArduinoOpenLoopInverterGUIUIFigure,["Emergency stop was sent.","Needs to be released before operation can continue"],"Emergency Stop","Icon","warning");
                   focus(app.ArduinoOpenLoopInverterGUIUIFigure);

                   app.logdata_events(end+1,:) = {[timerel, datecode],'Emergengy Stop','Stop was sent'};
                   app.InfoEditField.Value = 'Emergency Stop was Sent! Need to release before continue!';

                else
                    uialert(app.ArduinoOpenLoopInverterGUIUIFigure,"Emergency stop released.","Emergency Stop","Icon","warning");
                    focus(app.ArduinoOpenLoopInverterGUIUIFigure);

                    runxmsec.l = 0;
                    runxmsec.h = 0;
                    act_timestamp.l = uint16(hex2dec('0x0001'));
                    act_timestamp.h = uint16(hex2dec('0x0000'));

                    %Send Emergency Stop Command
                    app.data2send = uint16([runxmsec.l runxmsec.h 0 0 0 0 0 0 0 0 0 0 act_timestamp.l act_timestamp.h]);
                    write(app.u,app.data2send,"uint16","255.255.255.255",app.arduinoport); 
                    app.InfoEditField.Value = 'Emergency Release Command Sent';
                end
            end

        end

        % Button pushed function: Clear_LogBufferButton
        function Clear_LogBufferButtonPushed(app, event)
            selection = uiconfirm(app.ArduinoOpenLoopInverterGUIUIFigure,"Do you want to clear the datalog buffer without save?","Confirm Buffer Reset","Icon","warning");
            focus(app.ArduinoOpenLoopInverterGUIUIFigure);
            switch selection
                case 'OK'
                    app.logdata_rec = {};
                    app.logdata_snd = [];
                    app.logdata_snd_real = [];
                    app.logdata_events = {};
                    app.ZES_log = [];
                                                           

                    app.InfoEditField.Value = 'buffers cleared';
                case 'Cancel'
                    app.InfoEditField.Value = 'canceled';
            return
            end
        end

        % Value changed function: TexttoplaceinlogfileEditField
        function TexttoplaceinlogfileEditFieldValueChanged(app, event)
            value = app.TexttoplaceinlogfileEditField.Value;
            [timerel, datecode] = get_timestamp(app);
            app.logdata_events(end+1,:) = {[timerel, datecode],'User Text:',value};            
        end

        % Value changed function: ConnectPowerAnalyzerButton
        function ConnectPowerAnalyzerButtonValueChanged(app, event)
            value = app.ConnectPowerAnalyzerButton.Value;
            if (value == 1)
                disp('try to connect PA')

                IPZES = app.LocalIPEditField.Value; %get local IP
                pos = strfind(IPZES,'.');           
                IPZES = [IPZES(1:max(pos)),'41'];   %replace ending by 41
                if (length(pos)==3)                    
                    PA_init(app,IPZES);
                    %start(app.ZES_t);
                end
                
                app.ConnectPowerAnalyzerButton.BackgroundColor = [0 0.7 0];
                app.ConnectPowerAnalyzerButton.Text = 'Connected';
            else
                disp('disconnect PA')

                %stop(app.ZES_t);
                PA_close(app);

                app.ConnectPowerAnalyzerButton.BackgroundColor = [0.96,0.96,0.96];
                app.ConnectPowerAnalyzerButton.Text = 'Connect PowerAnalyzer';

            end
        end

        % Size changed function: ArduinoOpenLoopInverterGUIUIFigure
        function updateAppLayout(app, event)

        end

        % Button pushed function: Simulate
        function SimulateButtonPushed(app, event)
                        
            app.Simulate.Text = 'Wait Sim in Progress';
            app.Simulate.BackgroundColor = [0.8 0.2 0.2];

           try
           status = 0;
           app.InfoEditField.Value = 'Simulation needs Inputs';

           prompt = app.SimCondition(:,1);
           dlgtitle = 'Input Load and Supply';
           fieldsize = ones(length(prompt),2).*[1 45];
           definput = cellstr(string(app.SimCondition(:,2)));
           answer = inputdlg(prompt,dlgtitle,fieldsize,definput);

            if ~isempty(answer)
                app.SimCondition(:,2) = num2cell(str2double(answer));          
            status = 1;

            app.InfoEditField.Value = 'Simulation Start';

            in = Simulink.SimulationInput('GUI_Simulaton');
            %in = in.setModelParameter('RapidAcceleratorUpToDateCkeck','off');
            
             in = in.setVariable('PWMMode',uint8(find(strcmp(app.PWMModulationDropDown.Items, app.PWMModulationDropDown.Value))-1));
             in = in.setVariable('ASCMode',uint8(find(strcmp(app.StopOperationRamp_DownOFForASCDropDown.Items, app.StopOperationRamp_DownOFForASCDropDown.Value))-1));

             in = in.setVariable('ModIdx', 0.01*app.ModindexEditField.Value);
             in = in.setVariable('Speed',app.SpeedHzEditField.Value);
             in = in.setVariable('fsw',1e3*app.FswkHzEditField.Value);            

             [possync,hillholdpos] = getPosSync_HillholdPos(app);
             in = in.setVariable('possync', possync);
             in = in.setVariable('poshillhold', hillholdpos);  

             in = in.setVariable('Vdc',app.SimCondition{1,2}); 
             in = in.setVariable('LoadL',1e-6*app.SimCondition{2,2});
             in = in.setVariable('LoadR',1e-3*app.SimCondition{3,2});                     
            
            %constStruct1.time = [0 10]';
            %constStruct1.signals.values = ones(2,1).*0.01*app.ModindexEditField.Value;
            %inDS{1} = constStruct1;
            %in = setExternalInput(in,inDS);

            in = simulink.compiler.configureForDeployment(in);
            SimData = sim(in);

            
            app.InfoEditField.Value = 'Simulation Data now plotting';
            
            status = 2;
                       
            if isgraphics(1)
                close(1)
            end
            figure(1)

            set(gcf,"Position",[100 100 1200 1000])
            status = 3;
            ax(1) = subplot(311);
            cla(ax(1))

            hold on
            status = 4;
            DutyData = 100.*SimData.simout(:,2:4)./SimData.simout(:,1);
                DutyData(DutyData<0)=0;
                DutyData(DutyData>100)=100;
            Duty_e = (1/3)*sum(DutyData,2);
            plot(SimData.tout,DutyData)
            plot(SimData.tout,Duty_e,'g')

            %plot(SimData.tout,100.*SimData.simout(:,2:4)./SimData.simout(:,1))
            %plot(SimData.tout,100.*(1/3)*sum(SimData.simout(:,2:4),2)./SimData.simout(:,1),'g')
            title(ax(1),'Simulation Start till Steady State')
            ylim(ax(1),[-10 110])

             grid on
             ylabel(ax(1),{'Duty Cycles LS [%]','Highside (100%-LS%)'})
             %hold off
            status = 5;

            ax(2) = subplot(312);
            cla(ax(2))            
            plot(ax(2),SimData.tout,SimData.simout(:,7:9));
             ylabel(ax(2),'Voltage Vph [V]')
            hold on
            yyaxis right
            plot(ax(2),SimData.tout,SimData.simout(:,5));
            ylabel(ax(2),'TX1 Pin LogicLevel')
            title(ax(2),['Vdc=',num2str(app.SimCondition{1,2}),'V'])
            grid on


            ax(3) = subplot(313);
            cla(ax(3))            
            plot(ax(3),SimData.tout,SimData.simout(:,10:12));
            title(ax(3),['Vdc=',num2str(app.SimCondition{1,2}),'V; L=',num2str(app.SimCondition{2,2}),'uH; R=',num2str(app.SimCondition{3,2}),'mOhm'])
            if ax(3).YLim(2)<1
                ylim(ax(3),[-1 1]);
            end            
             ylabel(ax(3),'Current Iph [A]')
            grid on
             xlabel(ax(3),'time [s]')
             status = 6;
                        
            linkaxes(ax,'x')
            xlim([0 SimData.tout(end)])

            status = 7;
            app.InfoEditField.Value = ['Simulation DONE',num2str(status)];
            end
               
           catch
              app.InfoEditField.Value = ['Something crashed during simulation',num2str(status)];
           end

            app.Simulate.Text = 'Simulate';
            app.Simulate.BackgroundColor = [0.56 0.96 0.56];

        end

        % Button pushed function: adjustscaleButton
        function adjustscaleButtonPushed(app, event)
            %prompt = {'Vdc [V]:','Load L [uH]:','Load R [mOhm]:'};
            prompt = app.ScaleVal(:,1);
            dlgtitle = 'Input Data Scaling';
            fieldsize = ones(length(prompt),2).*[1 45];
            definput = cellstr(string(app.ScaleVal(:,2)));
            answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            if ~isempty(answer)
                app.ScaleVal(:,2)=num2cell(str2double(answer));
            end
            %windows should not disapear
            focus(app.ArduinoOpenLoopInverterGUIUIFigure);
        end

        % Button pushed function: resetscaleButton
        function resetscaleButtonPushed(app, event)
            selection = uiconfirm(app.ArduinoOpenLoopInverterGUIUIFigure,{'Reset scaling to factory default?';'Program will be closed and need to be restarted!'},'Confirm delete Scaling','Icon','warning');
            if strcmp(selection,'OK')                
                delete(fullfile(app.UserFolder,'',app.UserFile));
                %now close the file                
                ArduinoOpenLoopInverterGUIUIFigureCloseRequest(app, event);                
            end
        end

        % Button pushed function: loadscalefromfileButton
        function loadscalefromfileButtonPushed(app, event)
            try
                [file,location] = uigetfile(fullfile(pwd,'*.xlsx'),'Select an File for Scaling Values');
                if ~isequal(file,0)
                    app.ScaleVal = readcell(fullfile(location,file),'Sheet','Scale');
                    app.InfoEditField.Value = ['New Scaling Loaded from File: ',file]; 
                else
                    app.InfoEditField.Value = 'No Scaling File Selected';
                end
            catch
                app.InfoEditField.Value = 'Something was wrong at Scaling File Load';          
            end
            %windows should not disapear
            focus(app.ArduinoOpenLoopInverterGUIUIFigure);
        end

        % Button pushed function: FlashToolArduinoButton
        function FlashToolArduinoButtonPushed(app, event)
            
            selection = uiconfirm(app.ArduinoOpenLoopInverterGUIUIFigure,{'Attention: Risk to brick the Arduino. Continue on own risk!','','Do you really want to erase&flash a Arduino with a .bin file?','','Connect the Arduino Nano IoT 33 via USB to your PC.','Recommended to have the arduino not connected to any gate driver board, just to USB.'},...
                'Confirm Continue Flash Process','Icon','warning');

            switch selection
                case 'OK'
                    %disp('do')
                case 'Cancel'
                    focus(app.ArduinoOpenLoopInverterGUIUIFigure)
                    return             %leave the process
            end

            %test now COM port and Arduino available
            port = getArduinoCOMport(app);

            %test if bossac tool available
            try
                [status, cmdOut] = system('bossac --help');                
                bossac_available = strfind(cmdOut,'keep the 8KB of bootloader'); %check if bossac version keeps the bootloader!
            catch
                bossac_available = [];
            end
            
            if isempty(bossac_available)||isempty(port)
                %display error and status in GUI dialog
                if isempty(port)
                    COMportstatus = 'FAIL';
                else
                    COMportstatus = ['OK ',port];
                end
                if isempty(bossac_available)
                    bossacstatus = 'FAIL';
                else
                    bossacstatus = ['OK '];
                end
                %now display GUI with Error Status
                uialert(app.ArduinoOpenLoopInverterGUIUIFigure,{'The process will be stopped','',['COM Port: ',COMportstatus],'',['bossac flash tool: ',bossacstatus]},...
                'Error in Setup','CloseFcn',@(~, ~)fcn_devicedriverinst(app));
                                
                focus(app.ArduinoOpenLoopInverterGUIUIFigure)
                return % leave the process
            end

            try     %set Arduino to Bootloader Mode
                    s = serialport(port, 1200);
                    clear s; % Closing the port triggers the reset
            catch
            end

            %GUI select bin file
            if isdeployed
                location = dir(fullfile(ctfroot, '**', 'bossac.exe'));
            else
                location = dir(fullfile(pwd,'bossac.exe'));
            end            
            [binFile,binpath] = uigetfile(fullfile(location.folder,'*.bin'),'Select an *.bin File for Flash Process. Other location best copy .bin to this folder!');
            
            try
                %get again the COM port                
                port = getArduinoCOMport(app);
            catch
            end

            if ~isequal(binFile,0)
                %copy selected file to "toflashcopy.bin" in execution folder to avoid long file
                %names
                if isdeployed
                    copyfile(fullfile(binpath,binFile),fullfile(location.folder,'toflashcopy.bin'),'f');
                    app.InfoEditField.Value = fullfile(location.folder,'toflashcopy.bin');
                    binpath = location.folder;

                else
                    copyfile(fullfile(binpath,binFile),fullfile(pwd,'toflashcopy.bin'),'f');
                    binpath = pwd;
                end

                focus(app.ArduinoOpenLoopInverterGUIUIFigure)               


                %Last option to cancel. Inform about file and flash process
                %cmd = sprintf('bossac --info --port=%s -e -w -v %s -R', port, fullfile(binpath,binFile)); %construct the command
                cmd = sprintf('bossac --info --port=%s -e -w -v %s -R', port, fullfile(binpath,'toflashcopy.bin')); %construct the command                 
                selection = uiconfirm(app.ArduinoOpenLoopInverterGUIUIFigure,{'Last Check!','','Following flash command will be executed:',cmd,'','The 8K bootloader area will NOT be overwritten.','Click OK to flash or last option to cancel'},...
                    'Confirm File and Start Flash Process','Icon','info','Options',{'FLASH','Cancel'});

                switch selection
                case 'FLASH'
                    try

                    %FLASH
                    if ~isempty(port) 
                        [status, cmdOut] = system(cmd); %FLASH COMMAND
                        %Display the success status
                        focus(app.ArduinoOpenLoopInverterGUIUIFigure)
                        uialert(app.ArduinoOpenLoopInverterGUIUIFigure,cmdOut,'FLASH REPORT','Icon','success');                        
                    else
                        %Display FAILED
                        focus(app.ArduinoOpenLoopInverterGUIUIFigure)
                        uialert(app.ArduinoOpenLoopInverterGUIUIFigure,{'CRASHED!!!!','','',cmdOut},'FLASH REPORT','Icon','error');
                    end

                    catch
                        focus(app.ArduinoOpenLoopInverterGUIUIFigure)
                        app.InfoEditField.Value = 'Something crashed during flash process';
                        uialert(app.ArduinoOpenLoopInverterGUIUIFigure,{'Something was not working. Maybe Bootloader Mode not entered. Or any other fail'},'ERROR REPORT','Icon','error');
                    end

                    %delete the tempfile
                    if isdeployed
                        try
                            delete(fullfile(location.folder,'toflashcopy.bin'));
                        catch
                        end
                    else
                        try
                            delete(fullfile(pwd,'toflashcopy.bin'));
                        catch
                        end
                    end

                    focus(app.ArduinoOpenLoopInverterGUIUIFigure)

                case 'Cancel'
                    focus(app.ArduinoOpenLoopInverterGUIUIFigure)
                    app.InfoEditField.Value = 'User cancled before flash process';
                    return             %leave the process
                end
            end

        end

        % Value changed function: LockComChannelButton
        function LockComChannelButtonValueChanged(app, event)
            %value = app.LockComChannelButton.Value;
            
            IP = strsplit(app.LocalIPEditField.Value,'.'); 
            IP = uint16(str2double(cell2mat(IP(end))));

            write(app.u,uint16([IP, hex2dec('FFFF')-IP]),"uint16","255.255.255.255",app.arduinoport); 
            %app.InfoEditField.Value = 'LOCK 1';
            pause(0.5)
            write(app.u,uint16([app.SessionKey+IP, IP]),"uint16","255.255.255.255",app.arduinoport); 
            %app.InfoEditField.Value = 'LOCK 2';
            pause(0.5)
            write(app.u,uint16([app.SessionKey+IP, app.SessionKey+IP]),"uint16","255.255.255.255",app.arduinoport);
            app.InfoEditField.Value = ['LOCKED Comunication with KEY:',num2str(app.SessionKey+IP),'. Disconnect and Reset Arduino to release Lock.'];
            app.LockComChannelButton.Text = ['KEY:',num2str(app.SessionKey+IP)];
            
            app.LockMode = uint16(app.SessionKey+IP);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ArduinoOpenLoopInverterGUIUIFigure = uifigure('Visible', 'off');
            app.ArduinoOpenLoopInverterGUIUIFigure.Name = 'Arduino Open Loop Inverter GUI';
            app.ArduinoOpenLoopInverterGUIUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.ArduinoOpenLoopInverterGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @ArduinoOpenLoopInverterGUIUIFigureCloseRequest, true);
            app.ArduinoOpenLoopInverterGUIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ArduinoOpenLoopInverterGUIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.DirectControlCheckBox = uicheckbox(app.GridLayout2);
            app.DirectControlCheckBox.ValueChangedFcn = createCallbackFcn(app, @DirectControlCheckBoxValueChanged, true);
            app.DirectControlCheckBox.Text = 'Direct Control';
            app.RUNButton = uibutton(app.GridLayout2, 'state');
            app.RUNButton.ValueChangedFcn = createCallbackFcn(app, @RUNButtonValueChanged, true);
            app.RUNButton.Text = 'RUN';
            app.ConnectButton = uibutton(app.GridLayout2, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.WIFIInverterControlLabel = uilabel(app.GridLayout2);
            app.WIFIInverterControlLabel.Text = 'WIFI Inverter Control';
            app.Replay_LogfileButton = uibutton(app.GridLayout2, 'push');
            app.Replay_LogfileButton.ButtonPushedFcn = createCallbackFcn(app, @Replay_LogfileButtonPushed, true);
            app.Replay_LogfileButton.Text = 'Replay_Logfile';
            app.Save_LogfileButton = uibutton(app.GridLayout2, 'push');
            app.Save_LogfileButton.ButtonPushedFcn = createCallbackFcn(app, @Save_LogfileButtonPushed, true);
            app.Save_LogfileButton.Text = 'Save_Logfile';
            app.ModindexSliderLabel = uilabel(app.GridLayout2);
            app.ModindexSliderLabel.Text = 'Modindex [%]';
            app.ModindexSlider = uislider(app.GridLayout2);
            app.ModindexSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ModindexSlider.ValueChangingFcn = createCallbackFcn(app, @ModindexSliderValueChanging, true);
            app.ModindexSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.ModindexSlider.Value = 1;
            app.SpeedHzSliderLabel = uilabel(app.GridLayout2);
            app.SpeedHzSliderLabel.Text = 'Speed [Hz]';
            app.SpeedHzSlider = uislider(app.GridLayout2);
            app.SpeedHzSlider.Limits = [0 200];
            app.SpeedHzSlider.MajorTicks = [0 25 50 75 100 125 150 175 200];
            app.SpeedHzSlider.ValueChangingFcn = createCallbackFcn(app, @SpeedHzSliderValueChanging, true);
            app.SpeedHzSlider.MinorTicks = [];
            app.SpeedHzSlider.Value = 50;
            app.RunxSecSliderLabel = uilabel(app.GridLayout2);
            app.RunxSecSliderLabel.Text = 'Run x Sec';
            app.RunxSecSlider = uislider(app.GridLayout2);
            app.RunxSecSlider.Limits = [0 60];
            app.RunxSecSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.RunxSecSlider.ValueChangingFcn = createCallbackFcn(app, @RunxSecSliderValueChanging, true);
            app.RunxSecSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.RunxSecSlider.Value = 5;
            app.FswkHzSliderLabel = uilabel(app.GridLayout2);
            app.FswkHzSliderLabel.Text = 'Fsw [kHz]';
            app.FswkHzSlider = uislider(app.GridLayout2);
            app.FswkHzSlider.Limits = [2 20];
            app.FswkHzSlider.MajorTicks = [1 2 4 6 8 10 12 14 16 18 20];
            app.FswkHzSlider.MajorTickLabels = {'1', '2', '4', '6', '8', '10', '12', '14', '16', '18', '20'};
            app.FswkHzSlider.ValueChangingFcn = createCallbackFcn(app, @FswkHzSliderValueChanging, true);
            app.FswkHzSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.FswkHzSlider.Value = 10;
            app.LocalIPEditField_2Label = uilabel(app.GridLayout2);
            app.LocalIPEditField_2Label.Text = 'Local IP';
            app.LocalIPEditField = uieditfield(app.GridLayout2, 'text');
            app.LocalIPEditField.Editable = 'off';
            app.LocalIPEditField.Enable = 'off';
            app.LocalIPEditField.Value = 'auto detect';
            app.LocalSSIDEditFieldLabel = uilabel(app.GridLayout2);
            app.LocalSSIDEditFieldLabel.Text = 'Local SSID';
            app.LocalSSIDEditField = uieditfield(app.GridLayout2, 'text');
            app.LocalSSIDEditField.Editable = 'off';
            app.LocalSSIDEditField.Enable = 'off';
            app.LocalSSIDEditField.Value = 'auto detect';
            app.LocalIPDropDownLabel = uilabel(app.GridLayout2);
            app.LocalIPDropDownLabel.Text = 'Local IP';
            app.LocalIPDropDown = uidropdown(app.GridLayout2);
            app.LocalIPDropDown.Items = {'auto detect', '0.0.0.0'};
            app.LocalIPDropDown.Value = 'auto detect';
            app.runxsecsLabel = uilabel(app.GridLayout2);
            app.runxsecsLabel.Text = 'run x sec [s]';
            app.runxsecsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.runxsecsEditField.Limits = [0 3600];
            app.runxsecsEditField.ValueDisplayFormat = '%.2g';
            app.runxsecsEditField.ValueChangedFcn = createCallbackFcn(app, @runxsecsEditFieldValueChanged, true);
            app.runxsecsEditField.Value = 5;
            app.ModIndexLabel = uilabel(app.GridLayout2);
            app.ModIndexLabel.Text = 'ModIndex [%]';
            app.ModindexEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ModindexEditField.Limits = [0 180];
            app.ModindexEditField.ValueDisplayFormat = '%.3g';
            app.ModindexEditField.ValueChangedFcn = createCallbackFcn(app, @ModindexEditFieldValueChanged, true);
            app.SpeedHzLabel = uilabel(app.GridLayout2);
            app.SpeedHzLabel.Text = 'Speed [Hz]';
            app.SpeedHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpeedHzEditField.Limits = [0 1000];
            app.SpeedHzEditField.ValueDisplayFormat = '%.3g';
            app.SpeedHzEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedHzEditFieldValueChanged, true);
            app.SpeedHzEditField.Value = 100;
            app.fswkHzLabel = uilabel(app.GridLayout2);
            app.fswkHzLabel.Text = 'fsw [kHz]';
            app.FswkHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FswkHzEditField.Limits = [2 60];
            app.FswkHzEditField.ValueDisplayFormat = '%.3g';
            app.FswkHzEditField.ValueChangedFcn = createCallbackFcn(app, @FswkHzEditFieldValueChanged, true);
            app.FswkHzEditField.Value = 10;
            app.DTnsLabel = uilabel(app.GridLayout2);
            app.DTnsLabel.Text = 'DT [ns]';
            app.DTnsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DTnsEditField.Limits = [0 2500];
            app.DTnsEditField.ValueDisplayFormat = '%.0f';
            app.DTnsEditField.ValueChangedFcn = createCallbackFcn(app, @DTnsEditFieldValueChanged, true);
            app.DTnsEditField.Value = 1000;
            app.DeadtimensLabel = uilabel(app.GridLayout2);
            app.DeadtimensLabel.Text = 'Deadtime [ns]';
            app.DeadtimensSlider = uislider(app.GridLayout2);
            app.DeadtimensSlider.Limits = [0 2500];
            app.DeadtimensSlider.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000 1200 1400 1600 1800 2000 2200 2400];
            app.DeadtimensSlider.ValueChangingFcn = createCallbackFcn(app, @DeadtimensSliderValueChanging, true);
            app.AdvancedControlnotoverwrittenfromreplayfileLabel = uilabel(app.GridLayout2);
            app.AdvancedControlnotoverwrittenfromreplayfileLabel.Text = 'Advanced Control (at replay file, the selection below will be applied, not from file!)';
            app.PinTX0SyncwithPhaseDropDownLabel = uilabel(app.GridLayout2);
            app.PinTX0SyncwithPhaseDropDownLabel.Text = 'Pin TX0 Sync with Phase';
            app.PinTX0SyncwithPhaseDropDown = uidropdown(app.GridLayout2);
            app.PinTX0SyncwithPhaseDropDown.Items = {'Phase W', 'Phase V', 'Phase U'};
            app.PinTX0SyncwithPhaseDropDown.Value = 'Phase W';
            app.StopOperationRamp_DownOFForASCLabel = uilabel(app.GridLayout2);
            app.StopOperationRamp_DownOFForASCLabel.Text = {'Stop Operation'; '(Ramp_Down&OFF) or (ASC)'};
            app.StopOperationRamp_DownOFForASCDropDown = uidropdown(app.GridLayout2);
            app.StopOperationRamp_DownOFForASCDropDown.Items = {'Normal Ramp & OFF', 'ASC Lowside', 'ASC Highside'};
            app.StopOperationRamp_DownOFForASCDropDown.Value = 'Normal Ramp & OFF';
            app.HillHoldOperationDropDownLabel = uilabel(app.GridLayout2);
            app.HillHoldOperationDropDownLabel.Text = 'HillHold Operation';
            app.HillHoldOperationDropDown = uidropdown(app.GridLayout2);
            app.HillHoldOperationDropDown.Items = {'Normal no HillHold', 'Diode LS_W', 'Diode HS_V', 'Diode LS_U', 'Diode HS_W', 'Diode LS_V', 'Diode HS_U'};
            app.HillHoldOperationDropDown.ValueChangedFcn = createCallbackFcn(app, @HillHoldOperationDropDownValueChanged, true);
            app.HillHoldOperationDropDown.Value = 'Normal no HillHold';
            app.PWMModulationDropDownLabel = uilabel(app.GridLayout2);
            app.PWMModulationDropDownLabel.Text = 'PWM Modulation';
            app.PWMModulationDropDown = uidropdown(app.GridLayout2);
            app.PWMModulationDropDown.Items = {'SVPWM', 'SPWM Sine', 'THISPWM', 'DPWM1 (center)', 'DPWM2  (30deg delayed)', 'DPWM0  (30deg leading)', 'DPWM3 (sym +-30deg)', 'DPWMmin', 'DPWMmax'};
            app.PWMModulationDropDown.Value = 'SVPWM';
            app.CosphiTX0SliderLabel = uilabel(app.GridLayout2);
            app.CosphiTX0SliderLabel.Text = 'Cosphi TX0';
            app.CosphiTX0Slider = uislider(app.GridLayout2);
            app.CosphiTX0Slider.Limits = [-1 1];
            app.CosphiTX0Slider.MajorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CosphiTX0Slider.MinorTicks = [-1 -0.95 -0.9 -0.85 -0.8 -0.75 -0.7 -0.65 -0.6 -0.55 -0.5 -0.45 -0.4 -0.35 -0.3 -0.25 -0.2 -0.15 -0.1 -0.0499999999999999 0 0.0499999999999999 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.EmergencyStopButton = uibutton(app.GridLayout2, 'state');
            app.EmergencyStopButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyStopButtonValueChanged, true);
            app.EmergencyStopButton.Text = {'Emergency'; 'Stop'};
            app.Clear_LogBufferButton = uibutton(app.GridLayout2, 'push');
            app.Clear_LogBufferButton.ButtonPushedFcn = createCallbackFcn(app, @Clear_LogBufferButtonPushed, true);
            app.Clear_LogBufferButton.Text = 'Clear_LogBuffer';
            app.InfoEditFieldLabel = uilabel(app.GridLayout2);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.GridLayout2, 'text');
            app.InfoEditField.Editable = 'off';
            app.TexttoplaceinlogfileEditFieldLabel = uilabel(app.GridLayout2);
            app.TexttoplaceinlogfileEditFieldLabel.Text = 'Text to place in logfile';
            app.TexttoplaceinlogfileEditField = uieditfield(app.GridLayout2, 'text');
            app.TexttoplaceinlogfileEditField.ValueChangedFcn = createCallbackFcn(app, @TexttoplaceinlogfileEditFieldValueChanged, true);
            app.TexttoplaceinlogfileEditField.Tooltip = {'changing this text will be logged in event sheet'};
            app.Simulate = uibutton(app.GridLayout2, 'push');
            app.Simulate.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.Simulate.Text = 'Simulate';
            app.FlashToolArduinoButton = uibutton(app.GridLayout2, 'push');
            app.FlashToolArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @FlashToolArduinoButtonPushed, true);
            app.FlashToolArduinoButton.Text = 'FlashTool Arduino';
            app.LockComChannelButton = uibutton(app.GridLayout2, 'state');
            app.LockComChannelButton.ValueChangedFcn = createCallbackFcn(app, @LockComChannelButtonValueChanged, true);
            app.LockComChannelButton.Visible = 'off';
            app.LockComChannelButton.Text = 'LockComChannel';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.DataReceivedfromInverterLabel = uilabel(app.GridLayout3);
            app.DataReceivedfromInverterLabel.Text = 'Data Received from Inverter';
            app.RevisionLabel = uilabel(app.GridLayout3);
            app.RevisionLabel.Text = 'Revision';
            app.UDP_TimestampEditFieldLabel = uilabel(app.GridLayout3);
            app.UDP_TimestampEditFieldLabel.Text = 'UDP_Timestamp';
            app.rec_UDP_TimestampEditField = uieditfield(app.GridLayout3, 'text');
            app.rec_UDP_TimestampEditField.Editable = 'off';
            app.ctrl_TimestampEditFieldLabel = uilabel(app.GridLayout3);
            app.ctrl_TimestampEditFieldLabel.Text = 'ctrl_Timestamp';
            app.rec_ctrl_TimestampEditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_ctrl_TimestampEditField.ValueDisplayFormat = '%.0f';
            app.rec_ctrl_TimestampEditField.Editable = 'off';
            app.Status_RunEditFieldLabel = uilabel(app.GridLayout3);
            app.Status_RunEditFieldLabel.Text = 'Status_Run';
            app.rec_Status_RunEditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_Status_RunEditField.ValueDisplayFormat = '%.0f';
            app.rec_Status_RunEditField.Editable = 'off';
            app.VdcADCrawLabel = uilabel(app.GridLayout3);
            app.VdcADCrawLabel.Text = 'Vdc [ADC raw]';
            app.rec_Vdc_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_Vdc_EditField.ValueDisplayFormat = '%.0f';
            app.rec_Vdc_EditField.Editable = 'off';
            app.VdcADCrawLabel_2 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_2.Text = 'DATA LS avg [raw]';
            app.rec_DATA6avg_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_DATA6avg_EditField.ValueDisplayFormat = '%.0f';
            app.rec_DATA6avg_EditField.Editable = 'off';
            app.VdcADCrawLabel_3 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_3.Text = 'DATA HS avg [raw]';
            app.rec_DATA5avg_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_DATA5avg_EditField.ValueDisplayFormat = '%.0f';
            app.rec_DATA5avg_EditField.Editable = 'off';
            app.VdcADCrawLabel_4 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_4.Text = 'DATA LS min [raw]';
            app.rec_DATA6min_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_DATA6min_EditField.ValueDisplayFormat = '%.0f';
            app.rec_DATA6min_EditField.Editable = 'off';
            app.VdcADCrawLabel_5 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_5.Text = 'DATA HS min [raw]';
            app.rec_DATA5min_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_DATA5min_EditField.ValueDisplayFormat = '%.0f';
            app.rec_DATA5min_EditField.Editable = 'off';
            app.VdcADCrawLabel_6 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_6.Text = 'I Phase U [RAWrms]';
            app.rec_IU_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_IU_EditField.ValueDisplayFormat = '%.0f';
            app.rec_IU_EditField.Editable = 'off';
            app.VdcADCrawLabel_7 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_7.Text = 'I Phase V [RAWrms]';
            app.rec_IV_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_IV_EditField.ValueDisplayFormat = '%.0f';
            app.rec_IV_EditField.Editable = 'off';
            app.VdcADCrawLabel_8 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_8.Text = 'I Phase W [RAWrms]';
            app.rec_IW_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_IW_EditField.ValueDisplayFormat = '%.0f';
            app.rec_IW_EditField.Editable = 'off';
            app.VdcADCrawLabel_9 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_9.Text = 'Test Var 1 [raw]';
            app.rec_testvar1_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_testvar1_EditField.ValueDisplayFormat = '%.0f';
            app.rec_testvar1_EditField.Editable = 'off';
            app.VdcADCrawLabel_10 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_10.Text = 'Test Var 2 [raw]';
            app.rec_testvar2_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_testvar2_EditField.ValueDisplayFormat = '%.0f';
            app.rec_testvar2_EditField.Editable = 'off';
            app.ConnectPowerAnalyzerButton = uibutton(app.GridLayout3, 'state');
            app.ConnectPowerAnalyzerButton.ValueChangedFcn = createCallbackFcn(app, @ConnectPowerAnalyzerButtonValueChanged, true);
            app.ConnectPowerAnalyzerButton.Text = 'Connect PowerAnalyzer';
            app.PAVdcVrmsEditFieldLabel = uilabel(app.GridLayout3);
            app.PAVdcVrmsEditFieldLabel.Text = 'PA Vdc [Vrms]';
            app.PAVdcVrmsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PAVdcVrmsEditField.ValueDisplayFormat = '%5.1f';
            app.PAVdcVrmsEditField.Editable = 'off';
            app.PAIdcArmsEditFieldLabel = uilabel(app.GridLayout3);
            app.PAIdcArmsEditFieldLabel.Text = 'PA Idc [Arms]';
            app.PAIdcArmsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PAIdcArmsEditField.ValueDisplayFormat = '%5.1f';
            app.PAIdcArmsEditField.Editable = 'off';
            app.PAIUArmsEditFieldLabel = uilabel(app.GridLayout3);
            app.PAIUArmsEditFieldLabel.Text = 'PA IU [Arms]';
            app.PAIUArmsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PAIUArmsEditField.ValueDisplayFormat = '%5.1f';
            app.PAIUArmsEditField.Editable = 'off';
            app.PAIVArmsEditFieldLabel = uilabel(app.GridLayout3);
            app.PAIVArmsEditFieldLabel.Text = 'PA IV [Arms]';
            app.PAIVArmsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PAIVArmsEditField.ValueDisplayFormat = '%5.1f';
            app.PAIVArmsEditField.Editable = 'off';
            app.PAIWArmsEditFieldLabel = uilabel(app.GridLayout3);
            app.PAIWArmsEditFieldLabel.Text = 'PA IW [Arms]';
            app.PAIWArmsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PAIWArmsEditField.ValueDisplayFormat = '%5.1f';
            app.PAIWArmsEditField.Editable = 'off';
            app.PAPlossWEditFieldLabel = uilabel(app.GridLayout3);
            app.PAPlossWEditFieldLabel.Text = 'PA Ploss [W]';
            app.PAPlossWEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PAPlossWEditField.ValueDisplayFormat = '%5.1f';
            app.PAPlossWEditField.Editable = 'off';
            app.adjustscaleButton = uibutton(app.GridLayout3, 'push');
            app.adjustscaleButton.ButtonPushedFcn = createCallbackFcn(app, @adjustscaleButtonPushed, true);
            app.adjustscaleButton.Text = 'adjust scale';
            app.VdcADCrawLabel_11 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_11.Text = '[V]';
            app.rec_Vdc_scaled_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_Vdc_scaled_EditField.ValueDisplayFormat = '%.1f';
            app.rec_Vdc_scaled_EditField.Editable = 'off';
            app.VdcADCrawLabel_12 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_12.Text = '[°C]';
            app.rec_DATA6avg_scaled_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_DATA6avg_scaled_EditField.ValueDisplayFormat = '%.1f';
            app.rec_DATA6avg_scaled_EditField.Editable = 'off';
            app.VdcADCrawLabel_13 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_13.Text = '[°C]';
            app.rec_DATA5avg_scaled_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_DATA5avg_scaled_EditField.ValueDisplayFormat = '%.1f';
            app.rec_DATA5avg_scaled_EditField.Editable = 'off';
            app.VdcADCrawLabel_14 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_14.Text = '[°C]';
            app.rec_DATA6min_scaled_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_DATA6min_scaled_EditField.ValueDisplayFormat = '%.1f';
            app.rec_DATA6min_scaled_EditField.Editable = 'off';
            app.VdcADCrawLabel_15 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_15.Text = '[°C]';
            app.rec_DATA5min_scaled_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_DATA5min_scaled_EditField.ValueDisplayFormat = '%.1f';
            app.rec_DATA5min_scaled_EditField.Editable = 'off';
            app.resetscaleButton = uibutton(app.GridLayout3, 'push');
            app.resetscaleButton.ButtonPushedFcn = createCallbackFcn(app, @resetscaleButtonPushed, true);
            app.resetscaleButton.Text = 'reset scale';
            app.VdcADCrawLabel_16 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_16.Text = '[Arms]';
            app.rec_IU_scaled_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_IU_scaled_EditField.ValueDisplayFormat = '%.1f';
            app.rec_IU_scaled_EditField.Editable = 'off';
            app.VdcADCrawLabel_17 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_17.Text = '[Arms]';
            app.rec_IV_scaled_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_IV_scaled_EditField.ValueDisplayFormat = '%.1f';
            app.rec_IV_scaled_EditField.Editable = 'off';
            app.VdcADCrawLabel_18 = uilabel(app.GridLayout3);
            app.VdcADCrawLabel_18.Text = '[Arms]';
            app.rec_IW_scaled_EditField = uieditfield(app.GridLayout3, 'numeric');
            app.rec_IW_scaled_EditField.ValueDisplayFormat = '%.1f';
            app.rec_IW_scaled_EditField.Editable = 'off';
            app.loadscalefromfileButton = uibutton(app.GridLayout3, 'push');
            app.loadscalefromfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadscalefromfileButtonPushed, true);
            app.loadscalefromfileButton.Text = 'load scale from file';
            % Show the figure after all components are created
            app.ArduinoOpenLoopInverterGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_InvArduino

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ArduinoOpenLoopInverterGUIUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ArduinoOpenLoopInverterGUIUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArduinoOpenLoopInverterGUIUIFigure)
        end
    end
end